classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        RightPanel                    matlab.ui.container.Panel
        EstimatedValue                matlab.ui.control.NumericEditField
        EstimatedValueEditFieldLabel  matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
        LeftPanel                     matlab.ui.container.Panel
        Note1                         matlab.ui.control.NumericEditField
        NoofSimilarOrientatedNeedlesLabel  matlab.ui.control.Label
        Note2                         matlab.ui.control.Label
        EstimatedValueColourButtonGroup  matlab.ui.container.ButtonGroup
        OrangeButton                  matlab.ui.control.ToggleButton
        PurpleButton                  matlab.ui.control.ToggleButton
        GreenButton                   matlab.ui.control.ToggleButton
        BlueButton                    matlab.ui.control.ToggleButton
        RedButton                     matlab.ui.control.ToggleButton
        BlackButton                   matlab.ui.control.ToggleButton
        FontSlider                    matlab.ui.control.Slider
        EstimatedValueFontSizeSliderLabel  matlab.ui.control.Label
        WidthEditField                matlab.ui.control.NumericEditField
        WidthEditFieldLabel           matlab.ui.control.Label
        LengthEditField               matlab.ui.control.NumericEditField
        LengthEditFieldLabel          matlab.ui.control.Label
        Label                         matlab.ui.control.Label
        throws_quantity               matlab.ui.container.ButtonGroup
        throws_1000000                matlab.ui.control.RadioButton
        throws_100000                 matlab.ui.control.RadioButton
        throws_10000                  matlab.ui.control.RadioButton
        throws_1000                   matlab.ui.control.RadioButton
        throws_100                    matlab.ui.control.RadioButton
        throws_10                     matlab.ui.control.RadioButton
        ChooseaTask                   matlab.ui.control.DropDown
        ChooseaTaskTitle              matlab.ui.control.Label
    end

    
    methods (Access = private)
        
        function axplot(app,ax)
            task= getGlobalTask(app);
            throws = getGlobalThrows(app);
            length = getGlobalLength(app);
            width = getGlobalWidth(app);
            switch task
                case 'BuffonNeedlePi' %had to write out BuffonNeedlePi in the app in order to get Line Selection to function - would have figured out how to do it in function if I had more time
                  angles = rand(1,throws) * 360;
                  x1 = rand(1,throws) * 100;
                  y1 = rand(1,throws) * 100; 
                  if throws <= 1000
                      x1limit = zeros(1,throws);
                      x2limit = zeros(1,throws);
                      y1limit = zeros(1,throws);
                      y2limit = zeros(1,throws);
                      angleslimit = zeros(1,throws);
                  else
                      x1limit = zeros(1,1000);
                      x2limit =zeros(1,1000);
                      y1limit = zeros(1,1000);
                      y2limit = zeros(1,1000);
                      angleslimit = zeros(1,1000);
                  end
                  crossings=0;
                  limit=0;
                  cla(ax, 'reset');
                  hold(ax,"on");
                  for i=1:throws
                      crossed=0;
                      x2 = x1(i) + (length * cosd(angles(i)));
                      y2 = y1(i) + (length * sind(angles(i)));
                      if floor(y1(i)/width) ~= floor(y2/width)
                          crossings = crossings + 1;
                          crossed=1;
                      end
                      if limit<=1000
                          angleslimit(i) = angles(i);
                          x1limit(i) = x1(i);
                          y1limit(i) = y1(i);
                          x2limit(i) = x2;
                          y2limit(i) = y2;
                        if crossed>0
                          plot(ax,[x1(i),x2],[y1(i),y2],"red",'ButtonDownFcn',{@LineSelected,i});
                        else
                          plot(ax,[x1(i),x2],[y1(i),y2],"blue",'ButtonDownFcn',{@LineSelected,i});
                        end
                        limit=limit+1;
                      end
                  end
                  ylineplot = -(length):width:100 + length;
                  yline(ax,ylineplot);
                  estimate = (2*length*throws) / (width*crossings);
                case 'BuffonSquaresPi'
                    [estimate,crossings] = plotBuffonSquaresPi(ax,throws,length,width); %#ok<*ASGLU>
                case 'BuffonSquaresRootTwo'
                    [estimate,crossings] = plotBuffonSquaresRootTwo(ax,throws,length,width);
                case 'BuffonPentagonsGoldenRatio'
                    [estimate,crossings] = plotBuffonPentagonsGR(ax,throws,length,width);
                case 'BuffonPentagonsPi'
                    [estimate,crossings] = plotBuffonPentagonsPi(ax,throws,length,width);
                case 'BuffonHexagonsPi'
                    [estimate,crossings] = plotBuffonHexagonsPi(ax,throws,length,width);
                case 'BuffonHexagonsRootThree'
                    [estimate,crossings] = plotBuffonHexagonsRootThree(ax,throws,length,width);
            end
            app.EstimatedValue.Value = estimate;
            function LineSelected(src,~,i)
                src.Color="magenta";
                [angles_sorted,iterations] = sort(angleslimit);
                n = getGlobalN(app);
                i1 = mod(i-1,limit);
                i2 = mod(i+1,limit);
                if mod(n,2) == 0
                    for j=1:n
                        closestA = iterations(i1);
                        plot(ax,[x1limit(closestA),x2limit(closestA)],[y1limit(closestA),y2limit(closestA)],"green",'ButtonDownFcn',{@LineSelected,i,});
                        i1 = mod(i1 - 1,limit);
                        closestB = iterations(i2);
                        plot(ax,[x1limit(closestB),x2limit(closestB)],[y1limit(closestB),y2limit(closestB)],"green",'ButtonDownFcn',{@LineSelected,i});
                        i2 = mod(i2 + 1,limit);

                    end
                end
                if mod(n,2) == 1
                    total = n;
                    for j =1: n
                        if total > 1
                            closestA = iterations(i1);
                            plot(ax,[x1limit(closestA),x2limit(closestA)],[y1limit(closestA),y2limit(closestA)],"green",'ButtonDownFcn',{@LineSelected,i,});
                            i1 = mod(i1 - 1,limit);
                            closestB = iterations(i2);
                            plot(ax,[x1limit(closestB),x2limit(closestB)],[y1limit(closestB),y2limit(closestB)],"green",'ButtonDownFcn',{@LineSelected,i});
                            i2 = mod(i2 + 1,limit);
                        elseif total > 0
                            closestA = iterations(i1);
                            closestB = iterations(i2);
                            plot(ax,[x1limit(closestA),x2limit(closestA)],[y1limit(closestA),y2limit(closestA)],"green",'ButtonDownFcn',{@LineSelecated,i,});
                        end
                        total = total - 2;
                    end
                end
            end
        end
        
        function setGlobalTask(app,value) %#ok<*INUSD>
            global task;
            task=value;
        end
        function x = getGlobalTask(app) %#ok<*MANU,*DEFNU>
            global task;
            x = task;
        end

        function setGlobalThrows(app,value)
            global throws; 
            throws=value;
        end
        function x = getGlobalThrows(app)
            global throws;
            x = throws;
        end

        function setGlobalLength(app,value)
            global length;
            length=value;
        end
        function x = getGlobalLength(app)
            global length; %#ok<*GVMIS>
            x = length;
        end

        function setGlobalWidth(app,value)
            global width;
            width = value;
        end
        function x = getGlobalWidth(app)
            global width;
            x = width;
        end
        function setGlobalN(app,value)
            global n;
            n = value;
        end
        function x = getGlobalN(app)
            global n;
            x= n;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            setGlobalThrows(app,10);
            setGlobalLength(app,10);
            setGlobalWidth(app,10);
            setGlobalTask(app,"BuffonNeedlePi")
            setGlobalN(app,3);
            axplot(app,app.UIAxes)
        end

        % Selection changed function: throws_quantity
        function throws_quantitySelectionChanged(app, event)
            selectedButton = app.throws_quantity.SelectedObject;
            if selectedButton == app.throws_10
                setGlobalThrows(app,10);
            end
            if selectedButton == app.throws_100
                setGlobalThrows(app,100);
            end
            if selectedButton == app.throws_1000
                setGlobalThrows(app,1000);
            end
            if selectedButton == app.throws_10000
                setGlobalThrows(app,10000);
            end
            if selectedButton == app.throws_100000
                setGlobalThrows(app,100000);
            end
            if selectedButton == app.throws_1000000
                setGlobalThrows(app,1000000);
            end
            axplot(app,app.UIAxes);
        end

        % Value changed function: ChooseaTask
        function ChooseaTaskValueChanged(app, event)
            task = app.ChooseaTask.Value;
            setGlobalTask(app,task);
            axplot(app,app.UIAxes);
        end

        % Value changed function: LengthEditField
        function LengthEditFieldValueChanged(app, event)
            value = app.LengthEditField.Value;
            setGlobalLength(app,value);
            axplot(app,app.UIAxes);
        end

        % Value changed function: WidthEditField
        function WidthEditFieldValueChanged(app, event)
            value = app.WidthEditField.Value;
            setGlobalWidth(app,value);
            axplot(app,app.UIAxes);
        end

        % Value changed function: FontSlider
        function FontSliderValueChanged(app, event)
            value = app.FontSlider.Value;
            fontsize(app.EstimatedValue,value,"points");
        end

        % Selection changed function: EstimatedValueColourButtonGroup
        function EstimatedValueColourButtonGroupSelectionChanged(app, event)

            if app.RedButton.Value == true
                app.EstimatedValue.FontColor = "red";
            end
            if app.BlackButton.Value == true
                app.EstimatedValue.FontColor = "black";
            end
            if app.BlueButton.Value == true
                app.EstimatedValue.FontColor = "blue";
            end
            if app.GreenButton.Value == true
                app.EstimatedValue.FontColor = [0.39,0.83,0.07];
            end
            if app.PurpleButton.Value == true
                app.EstimatedValue.FontColor = [0.49 0.18 0.56];
            end
            if app.OrangeButton.Value == true
                app.EstimatedValue.FontColor = [1.00 0.41 0.16];
            end
        end

        % Value changed function: Note1
        function NChanged(app, event)
            value = app.Note1.Value;
            setGlobalN(app,value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.LeftPanel = uipanel(app.UIFigure);
            app.ChooseaTaskTitle = uilabel(app.LeftPanel);
            app.ChooseaTaskTitle.Text = 'Choose a Task';
            app.ChooseaTask = uidropdown(app.LeftPanel);
            app.ChooseaTask.Items = {'BuffonNeedlePi', 'BuffonSquaresPi', 'BuffonSquaresRootTwo', 'BuffonPentagonsPi', 'BuffonPentagonsGoldenRatio', 'BuffonHexagonsPi', 'BuffonHexagonsRootThree'};
            app.ChooseaTask.ValueChangedFcn = createCallbackFcn(app, @ChooseaTaskValueChanged, true);
            app.ChooseaTask.Value = 'BuffonNeedlePi';
            app.throws_quantity = uibuttongroup(app.LeftPanel);
            app.throws_quantity.SelectionChangedFcn = createCallbackFcn(app, @throws_quantitySelectionChanged, true);
            app.throws_quantity.Title = 'Number of Drops';
            app.throws_10 = uiradiobutton(app.throws_quantity);
            app.throws_10.Text = '10';
            app.throws_10.Value = true;
            app.throws_100 = uiradiobutton(app.throws_quantity);
            app.throws_100.Text = '100';
            app.throws_1000 = uiradiobutton(app.throws_quantity);
            app.throws_1000.Text = '1,000';
            app.throws_10000 = uiradiobutton(app.throws_quantity);
            app.throws_10000.Text = '10,000';
            app.throws_100000 = uiradiobutton(app.throws_quantity);
            app.throws_100000.Text = '100,000';
            app.throws_1000000 = uiradiobutton(app.throws_quantity);
            app.throws_1000000.Text = '1,000,000';
            app.Label = uilabel(app.LeftPanel);
            app.Label.Text = {'Note: Amount of plots made are limited '; 'for drop counts past 1000 to reduce runtime.'};
            app.LengthEditFieldLabel = uilabel(app.LeftPanel);
            app.LengthEditFieldLabel.Text = 'Length';
            app.LengthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.LengthEditField.ValueChangedFcn = createCallbackFcn(app, @LengthEditFieldValueChanged, true);
            app.LengthEditField.Placeholder = '10';
            app.LengthEditField.Value = 10;
            app.WidthEditFieldLabel = uilabel(app.LeftPanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.LeftPanel, 'numeric');
            app.WidthEditField.ValueChangedFcn = createCallbackFcn(app, @WidthEditFieldValueChanged, true);
            app.WidthEditField.Placeholder = '10';
            app.WidthEditField.Value = 10;
            app.EstimatedValueFontSizeSliderLabel = uilabel(app.LeftPanel);
            app.EstimatedValueFontSizeSliderLabel.Text = {'Estimated Value'; 'Font Size'};
            app.FontSlider = uislider(app.LeftPanel);
            app.FontSlider.Limits = [10 42];
            app.FontSlider.ValueChangedFcn = createCallbackFcn(app, @FontSliderValueChanged, true);
            app.FontSlider.Value = 10;
            app.EstimatedValueColourButtonGroup = uibuttongroup(app.LeftPanel);
            app.EstimatedValueColourButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EstimatedValueColourButtonGroupSelectionChanged, true);
            app.EstimatedValueColourButtonGroup.Title = 'Estimated Value Colour';
            app.BlackButton = uitogglebutton(app.EstimatedValueColourButtonGroup);
            app.BlackButton.Text = 'Black';
            app.BlackButton.Value = true;
            app.RedButton = uitogglebutton(app.EstimatedValueColourButtonGroup);
            app.RedButton.Text = 'Red';
            app.BlueButton = uitogglebutton(app.EstimatedValueColourButtonGroup);
            app.BlueButton.Text = 'Blue';
            app.GreenButton = uitogglebutton(app.EstimatedValueColourButtonGroup);
            app.GreenButton.Text = 'Green';
            app.PurpleButton = uitogglebutton(app.EstimatedValueColourButtonGroup);
            app.PurpleButton.Text = 'Purple';
            app.OrangeButton = uitogglebutton(app.EstimatedValueColourButtonGroup);
            app.OrangeButton.Text = 'Orange';
            app.Note2 = uilabel(app.LeftPanel);
            app.Note2.Text = {'Note: Estimations of  non-pi values '; 'work best where width is 2x length'};
            app.NoofSimilarOrientatedNeedlesLabel = uilabel(app.LeftPanel);
            app.NoofSimilarOrientatedNeedlesLabel.Text = {'No. of Similar '; 'Orientated Needles'};
            app.Note1 = uieditfield(app.LeftPanel, 'numeric');
            app.Note1.RoundFractionalValues = 'on';
            app.Note1.ValueChangedFcn = createCallbackFcn(app, @NChanged, true);
            app.Note1.Placeholder = '3';
            app.Note1.Value = 3;
            app.RightPanel = uipanel(app.UIFigure);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.EstimatedValueEditFieldLabel = uilabel(app.RightPanel);
            app.EstimatedValueEditFieldLabel.Text = 'Estimated Value:';
            app.EstimatedValue = uieditfield(app.RightPanel, 'numeric');
            app.EstimatedValue.AllowEmpty = 'on';
            app.EstimatedValue.Editable = 'off';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end